classdef ProjektAO_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        ReadtextButton     matlab.ui.control.Button
        ClearresultButton  matlab.ui.control.Button
        ResultLabel        matlab.ui.control.Label
        DataLabel          matlab.ui.control.Label
        SaveResultButton   matlab.ui.control.Button
        outputText         matlab.ui.control.Label
        LoadImageButton    matlab.ui.control.Button
        inputTextImage     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ProjektAO_OpeningFcn(app, varargin)
            movegui(app.figure1, 'onscreen');
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); 
            handles.output = hObject;
            guidata(hObject, handles);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButton_Callback(app, event)

            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 

            global regionHeight;
            global regionWidth;
            global toRead;
            global code;
            global codeLen;         
            regionHeight = 20;
            regionWidth = 20; 
            [filename, pathname] = uigetfile({'*.png';'*.jpg'},'File Selector');            
            toRead = strcat(pathname, filename);
            image = imread(toRead);
            imshow(image);
            code = ['A' 'a' 'B' 'b' 'C' 'c' 'D' 'd' 'E' 'e' 'F' 'f' 'G' 'g' 'H' 'h' 'I' 'i' 'J' 'j' 'K' 'k' 'L' 'l' 'M' 'm' 'N' 'n' 'O' 'o' 'P' 'p' 'R' 'r' 'S' 's' 'T' 't' 'U' 'u' 'V' 'v' 'W' 'w' 'X' 'x' 'Y' 'y' 'Z' 'z' ',' '.'];
            [~, codeLen] = size(code);
            
        end

        % Button pushed function: SaveResultButton
        function saveResult(app, event)
            filter = {'*.txt';'*.*'};
            [file, path] = uiputfile(filter);
            figure(app.figure1)
            filename = fullfile(path,file);
            if isequal(file,0) || isequal(path,0)
                disp('Canceled.')
            else
                fileWrite = fopen(filename,'wt');
                fprintf(fileWrite,'%s', app.outputText.Text{:});
                fclose(fileWrite);
            end
        end

        % Button pushed function: ClearresultButton
        function ClearresultButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            set(handles.outputText ,'String', '');
        end

        % Button pushed function: ReadtextButton
        function ReadtextButtonButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            global toRead;

            datasetImage = helperFunctions.readImage('dataset_letters.png');
            datasetNormalized = normalization.getLettersFromImage(datasetImage);

            textImage = helperFunctions.readImage(toRead);

            text = main.decryptText(datasetNormalized, textImage);
            set(handles.outputText ,'String', text);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ProjektAO';
            app.figure1.Tag = 'figure1';
            app.inputTextImage = uiaxes(app.figure1);
            app.inputTextImage.XTick = [];
            app.inputTextImage.XTickLabel = {};
            app.inputTextImage.YTick = [];
            app.inputTextImage.YTickLabel = '';
            app.inputTextImage.Tag = 'inputTextImage';
            app.LoadImageButton = uibutton(app.figure1, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButton_Callback, true);
            app.LoadImageButton.Tag = 'pushbutton1';
            app.LoadImageButton.Text = 'Load image';
            app.outputText = uilabel(app.figure1);
            app.outputText.Tag = 'outputText';
            app.outputText.Text = '';
            app.SaveResultButton = uibutton(app.figure1, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @saveResult, true);
            app.SaveResultButton.Text = 'Save result';
            app.DataLabel = uilabel(app.figure1);
            app.DataLabel.Text = 'Data:';
            app.ResultLabel = uilabel(app.figure1);
            app.ResultLabel.Text = 'Result:';
            app.ClearresultButton = uibutton(app.figure1, 'push');
            app.ClearresultButton.ButtonPushedFcn = createCallbackFcn(app, @ClearresultButtonPushed, true);
            app.ClearresultButton.Tag = 'PushButtonClear';
            app.ClearresultButton.Tooltip = {'Clear Data and Result fileds'};
            app.ClearresultButton.Text = 'Clear result';
            app.ReadtextButton = uibutton(app.figure1, 'push');
            app.ReadtextButton.ButtonPushedFcn = createCallbackFcn(app, @ReadtextButtonButtonPushed, true);
            app.ReadtextButton.Tag = 'pushbuttonReadText';
            app.ReadtextButton.Text = 'Read text';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjektAO_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)ProjektAO_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end